classdef DOAN_THPPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        NgimTab                         matlab.ui.container.Tab
        NhpsaischophpEditField          matlab.ui.control.NumericEditField
        NhpsaischophpEditFieldLabel     matlab.ui.control.Label
        NhpkhongphnlynghimEditField     matlab.ui.control.EditField
        NhpkhongphnlynghimEditFieldLabel  matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        SlnlpEditField                  matlab.ui.control.NumericEditField
        SlnlpLabel                      matlab.ui.control.Label
        KtqunghimEditField              matlab.ui.control.NumericEditField
        KtqunghimEditFieldLabel         matlab.ui.control.Label
        ChnphngphptmDropDown            matlab.ui.control.DropDown
        ChnphngphptmLabel               matlab.ui.control.Label
        NhpphngtrnhEditField            matlab.ui.control.EditField
        NhpphngtrnhLabel                matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        NisuyTab                        matlab.ui.container.Tab
        NhpdliuyEditField               matlab.ui.control.EditField
        NhpdliuyEditField_7Label        matlab.ui.control.Label
        NhpdliuxEditField               matlab.ui.control.EditField
        NhpdliuxEditField_6Label        matlab.ui.control.Label
        RunButton_2                     matlab.ui.control.Button
        KtquathcnisuyTextArea           matlab.ui.control.TextArea
        KtquathcnisuyLabel              matlab.ui.control.Label
        ChnphngphpnisuyDropDown         matlab.ui.control.DropDown
        ChnphngphpnisuyLabel            matlab.ui.control.Label
        KtqunisuyEditField              matlab.ui.control.NumericEditField
        KtqunisuyLabel                  matlab.ui.control.Label
        NhpgitrcnnisuyEditField         matlab.ui.control.NumericEditField
        NhpgitrcnnisuyLabel             matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        HiquyTab                        matlab.ui.container.Tab
        NhpdliuyEditField_2             matlab.ui.control.EditField
        NhpdliuyEditField_7Label_2      matlab.ui.control.Label
        NhpdliuxEditField_2             matlab.ui.control.EditField
        NhpdliuxEditField_6Label_2      matlab.ui.control.Label
        RunButton_3                     matlab.ui.control.Button
        KtquphngtrnhhiquyTextArea       matlab.ui.control.TextArea
        KtquphngtrnhhiquyLabel          matlab.ui.control.Label
        ChnphngphpnisuyDropDown_2       matlab.ui.control.DropDown
        ChnphngphpnisuyDropDown_2Label  matlab.ui.control.Label
        KtqudonEditField                matlab.ui.control.NumericEditField
        KtqudonLabel                    matlab.ui.control.Label
        NhpgitrcndonEditField           matlab.ui.control.NumericEditField
        NhpgitrcndonLabel               matlab.ui.control.Label
        UIAxes2_2                       matlab.ui.control.UIAxes
        ohmTab                          matlab.ui.container.Tab
        Nhpbc_hEditField                matlab.ui.control.NumericEditField
        NhpbchEditFieldLabel            matlab.ui.control.Label
        Nhphm_sEditField                matlab.ui.control.EditField
        NhpdliuyEditField_7Label_4      matlab.ui.control.Label
        NhpdliuyEditField_3             matlab.ui.control.EditField
        NhpdliuyEditField_7Label_3      matlab.ui.control.Label
        NhpdliuxEditField_3             matlab.ui.control.EditField
        NhpdliuxEditField_6Label_3      matlab.ui.control.Label
        RunButton_4                     matlab.ui.control.Button
        KtquTextArea                    matlab.ui.control.TextArea
        KtquLabel_2                     matlab.ui.control.Label
        NhpgitrcntnhohmEditField        matlab.ui.control.NumericEditField
        NhpgitrcntnhohmLabel            matlab.ui.control.Label
        ChnphngphpohmDropDown           matlab.ui.control.DropDown
        ChnphngphpohmLabel              matlab.ui.control.Label
        ChngitrsaisDropDown             matlab.ui.control.DropDown
        ChngitrsaisDropDownLabel        matlab.ui.control.Label
        HocLabel                        matlab.ui.control.Label
        TchphnTab                       matlab.ui.container.Tab
        NhpdliuyEditField_4             matlab.ui.control.EditField
        NhpdliuyEditField_5Label        matlab.ui.control.Label
        NhpdliuxEditField_4             matlab.ui.control.EditField
        NhpdliuxEditField_4Label        matlab.ui.control.Label
        RunButton_5                     matlab.ui.control.Button
        KtquEditField                   matlab.ui.control.NumericEditField
        KtquLabel                       matlab.ui.control.Label
        NhpNEditField                   matlab.ui.control.NumericEditField
        NhpNLabel                       matlab.ui.control.Label
        NhpcntnhtchphnEditField         matlab.ui.control.EditField
        NhpcntnhtchphnEditFieldLabel    matlab.ui.control.Label
        ChnphngphptchphnDropDown        matlab.ui.control.DropDown
        ChnphngphptchphnDropDownLabel   matlab.ui.control.Label
        NhphmsEditField                 matlab.ui.control.EditField
        NhphmsEditFieldLabel            matlab.ui.control.Label
        HocLabel_2                      matlab.ui.control.Label
        GiithiunhmTab                   matlab.ui.container.Tab
        RunButton_6                     matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
      
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
   
    % 1. THU THẬP DỮ LIỆU ĐẦU VÀO
    
    f_str = app.NhpphngtrnhEditField.Value; % Lấy phương trình dưới dạng chuỗi

    khoang_str = app.NhpkhongphnlynghimEditField.Value; % Lấy khoảng phân ly nghiệm [a, b]
    
    % Chuyển chuỗi khoảng phân ly thành 2 giá trị số [a, b]
    try
        % Nhập chuỗi bằng dấu phẩy, nếu không được thì cách nhau bằng khoảng trắng
        khoang_parts = strsplit(khoang_str, ',');
        if length(khoang_parts) ~= 2
             khoang_parts = strsplit(khoang_str, ' ');
        end

        a = str2double(strtrim(khoang_parts{1}));
        b = str2double(strtrim(khoang_parts{2}));
        if isnan(a) || isnan(b)
             uialert(app.UIFigure, 'Vui lòng nhập khoảng [a, b] đúng định dạng (ví dụ: 1, 2)','Lỗi đầu vào');
             return;
        end
    catch
        uialert(app.UIFigure, 'Vui lòng nhập khoảng [a, b] đúng định dạng (ví dụ: 1, 2)','Lỗi đầu vào');
        return;
    end
    
    % Lấy sai số cho phép
    epsilon = app.NhpsaischophpEditField.Value;
    
    % Lấy phương pháp đã chọn
    phuong_phap = app.ChnphngphptmDropDown.Value;
    
    % Khởi tạo hàm f(x) (dùng @(x) hoặc syms)
    f = str2sym(f_str);
    f_func = matlabFunction(f);
    
    % Khởi tạo biến kết quả
    x_nghiem = NaN;
    so_lan_lap = 0;


    % 2. VẼ ĐỒ THỊ TRƯỚC KHI TÌM NGHIỆM
    
    try
        % Tạo khoảng X để vẽ đồ thị
        plot_min = min(a, b) - 0.5;
        plot_max = max(a, b) + 0.5;
        X = linspace(plot_min, plot_max, 500); 
        Y = f_func(X);
        
        % Vẽ đồ thị
        plot(app.UIAxes, X, Y, 'LineWidth', 2);
        hold(app.UIAxes, 'on'); 
        plot(app.UIAxes, [plot_min plot_max], [0 0], 'r--'); % Vẽ trục x (f(x)=0)
        
        % Đánh dấu khoảng [a, b]
        plot(app.UIAxes, [a a], ylim(app.UIAxes), 'g:'); 
        plot(app.UIAxes, [b b], ylim(app.UIAxes), 'g:'); 
        
        title(app.UIAxes, 'Đồ thị hàm số và Khoảng phân ly');
        xlabel(app.UIAxes, 'X');
        ylabel(app.UIAxes, 'Y');
        hold(app.UIAxes, 'off');
        
    catch ME
        uialert(app.UIFigure, ['Lỗi khi vẽ đồ thị: ' ME.message], 'Lỗi Đồ thị');
        return;
    end


    % 3. THỰC HIỆN TÍNH TOÁN NGHIỆM SỐ
    
    if strcmp(phuong_phap, 'Chia đôi')
        % PHƯƠNG PHÁP CHIA ĐÔI
        
        % Kiểm tra điều kiện phân ly nghiệm
        if f_func(a) * f_func(b) > 0
            uialert(app.UIFigure, 'f(a) và f(b) cùng dấu. Vui lòng chọn khoảng phân ly nghiệm khác.', 'Lỗi Thuật toán');
            return;
        end
        
        c = (a + b) / 2;
        so_lan_lap = 0;
        
        while (b - a) / 2 > epsilon
            so_lan_lap = so_lan_lap + 1;
            c = (a + b) / 2;
            
            if f_func(c) == 0
                break;
            elseif f_func(a) * f_func(c) < 0
                b = c;
            else
                a = c;
            end
        end
        x_nghiem = (a + b) / 2;
        
    elseif strcmp(phuong_phap, 'Lặp') % PHƯƠNG PHÁP LẶP
        
        % Yêu cầu người dùng nhập giá trị khởi tạo x0
        x0 = (a + b) / 2; 
        
        % Lấy đạo hàm của f(x) 
        syms x
        f_sym = str2sym(f_str);
        
        % Hàm lặp đơn giản: x = g(x). Ta chọn g(x) sao cho |g'(x)| < 1 trên [a, b].
        df_sym = diff(f_sym, x);
        df_func = matlabFunction(df_sym);
        
        % Chọn c = 1/max|f'(x)| trên [a, b].
        if abs(df_func(x0)) < 1e-6
            uialert(app.UIFigure, 'Đạo hàm tại điểm khởi tạo quá nhỏ. Thử phương pháp khác hoặc khoảng khác.', 'Lỗi Thuật toán');
            return;
        end
        
        c = 1 / df_func(x0);
        g_sym = x - c * f_sym;
        g_func = matlabFunction(g_sym);
        
        % Kiểm tra điều kiện lặp: |g'(x)| < 1
        dg_sym = diff(g_sym, x);
        dg_func = matlabFunction(dg_sym);

        % Kiểm tra |g'(x)| tại 2 cận
        if abs(dg_func(a)) >= 1 || abs(dg_func(b)) >= 1 
            uialert(app.UIFigure, 'Điều kiện hội tụ |g''(x)| < 1 không đảm bảo trên khoảng này. Thử khoảng khác.', 'Lỗi Hội tụ');
            return;
        end
        
        x_prev = x0;
        so_lan_lap = 0;
        
        for k = 1:100 % Giới hạn số lần lặp
            x_curr = g_func(x_prev);
            so_lan_lap = k;
            
            if abs(x_curr - x_prev) < epsilon
                x_nghiem = x_curr;
                break;
            end
            x_prev = x_curr;
        end
        
    elseif strcmp(phuong_phap, 'Newton (tiếp tuyến)') % PHƯƠNG PHÁP NEWTON
        
        % Yêu cầu người dùng nhập giá trị khởi tạo x0 
        x0 = (a + b) / 2; 
        
        % Lấy đạo hàm của f(x) 
        syms x
        f_sym = str2sym(f_str);
        df_sym = diff(f_sym, x);
        
        f_func = matlabFunction(f_sym);
        df_func = matlabFunction(df_sym);
        
        x_prev = x0;
        so_lan_lap = 0;
        
        for k = 1:100 % Giới hạn số lần lặp
            so_lan_lap = k;
            
            % Kiểm tra đạo hàm f'(x) khác 0
            df_val = df_func(x_prev);
            if abs(df_val) < 1e-8
                uialert(app.UIFigure, 'Đạo hàm f''(x) gần bằng 0. Thuật toán không hội tụ.', 'Lỗi Thuật toán');
                return;
            end
            
            x_curr = x_prev - f_func(x_prev) / df_val;
            
            if abs(x_curr - x_prev) < epsilon
                x_nghiem = x_curr;
                break;
            end
            x_prev = x_curr;
        end
        
    end


    % 4. HIỂN THỊ KẾT QUẢ VÀ ĐÁNH DẤU TRÊN ĐỒ THỊ
    
    if ~isnan(x_nghiem)
        % Hiển thị nghiệm (đã hội tụ)
        app.KtqunghimEditField.Value = x_nghiem;
        app.SlnlpEditField.Value = so_lan_lap;
        
        % Đánh dấu nghiệm trên đồ thị 
        hold(app.UIAxes, 'on'); 
        plot(app.UIAxes, x_nghiem, f_func(x_nghiem), 'go', 'MarkerSize', 8, 'LineWidth', 2);
        hold(app.UIAxes, 'off');
    else
        % Hiển thị nếu không hội tụ
        app.KtqunghimEditField.Value = NaN;
        app.SlnlpEditField.Value = so_lan_lap;
        uialert(app.UIFigure, 'Không tìm thấy nghiệm hội tụ sau 100 lần lặp.', 'Lỗi Hội tụ');
    end      
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)

    % 1. THU THẬP VÀ XỬ LÝ DỮ LIỆU ĐẦU VÀO
    
    try
        % str2num: chuyển chuỗi số cách nhau bằng dấu cách hoặc dấu phẩy thành vector
        x_data = str2num(app.NhpdliuxEditField.Value); 
        y_data = str2num(app.NhpdliuyEditField.Value);
        x_interp = app.NhpgitrcnnisuyEditField.Value;  % Giá trị cần nội suy
        phuong_phap = app.ChnphngphpnisuyDropDown.Value;
        
        if isempty(x_data) || isempty(y_data) || length(x_data) ~= length(y_data)
            uialert(app.UIFigure, 'Vui lòng nhập dữ liệu x và y hợp lệ (cùng kích thước).', 'Lỗi Dữ liệu');
            return;
        end
        n = length(x_data);
    catch ME
        uialert(app.UIFigure, ['Lỗi nhập liệu: ' ME.message], 'Lỗi Đầu vào');
        return;
    end


    % 2. KHỞI TẠO BIẾN KẾT QUẢ VÀ SYMBOLIC
    
    syms x;
    P_sym = 0;   


    % 3. THỰC HIỆN TÍNH TOÁN NỘI SUY
    
    if strcmp(phuong_phap, 'Lagrange')
        % PHƯƠNG PHÁP NỘI SUY LAGRANGE
        
        for i = 1:n
            L_i = 1;
            % Tính toán đa thức cơ sở Lagrange L_i(x)
            for j = 1:n
                if i ~= j
                    L_i = L_i * (x - x_data(j)) / (x_data(i) - x_data(j));
                end
            end
            P_sym = P_sym + y_data(i) * L_i; % Cộng dồn đa thức P(x) = sum(y_i * L_i(x))
        end
        
    elseif strcmp(phuong_phap, 'Newton')
        % PHƯƠNG PHÁP NỘI SUY NEWTON
        
        % Tính bảng sai phân chia
        F = zeros(n, n);
        F(:, 1) = y_data'; % Cột đầu tiên là f[x_i]
        
        for j = 2:n
            for i = j:n
                % Công thức sai phân chia: F[i, j] = (F[i, j-1] - F[i-1, j-1]) / (x_data[i] - x_data[i-j+1])
                F(i, j) = (F(i, j-1) - F(i-1, j-1)) / (x_data(i) - x_data(i-j+1));
            end
        end
        
        % Các hệ số Newton là các phần tử trên đường chéo chính của F: F(1, 1), F(2, 2), ..., F(n, n)
        coeffs = diag(F)'; 
        
        % Xây dựng đa thức nội suy Newton P(x)
        P_sym = coeffs(1); % Bắt đầu với f[x0]
        T = 1; % Tích số (x - x_i)
        
        for k = 2:n
            % Tích T = (x - x_0)(x - x_1)...(x - x_{k-2})
            T = T * (x - x_data(k-1)); 
            P_sym = P_sym + coeffs(k) * T;
        end
    else
        uialert(app.UIFigure, 'Phương pháp nội suy chưa được chọn hoặc không hợp lệ.', 'Lỗi Thuật toán');
        return;
    end


    % 4. XỬ LÝ VÀ HIỂN THỊ KẾT QUẢ
    
    % Rút gọn đa thức và làm đẹp về dạng thập phân
    P_sym_simplified = vpa(simplify(P_sym), 6); 
    
    % Chuyển đa thức về dạng hàm để tính giá trị số
    P_func = matlabFunction(P_sym);
    
    % Tính kết quả nội suy số tại x_interp
    y_interp = P_func(x_interp);
    
    % Hiển thị Đa thức
    app.KtquathcnisuyTextArea.Value = char(P_sym_simplified);
    
    % Hiển thị Kết quả số
    app.KtqunisuyEditField.Value = y_interp;


    % 5. VẼ ĐỒ THỊ     
    try
        % Tạo khoảng X để vẽ đồ thị 
        x_min = min(x_data) - 0.1 * (max(x_data) - min(x_data));
        x_max = max(x_data) + 0.1 * (max(x_data) - min(x_data));
        X_plot = linspace(x_min, x_max, 500); 
        Y_plot = P_func(X_plot);
        
        % Xóa đồ thị cũ và thiết lập lại
        cla(app.UIAxes2);
        
        % Vẽ Đa thức nội suy
        plot(app.UIAxes2, X_plot, Y_plot, 'b-', 'LineWidth', 1.5, 'DisplayName', 'Đa thức nội suy'); 
        hold(app.UIAxes2, 'on'); 
        
        % Vẽ Dữ liệu thực
        plot(app.UIAxes2, x_data, y_data, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r', 'DisplayName', 'Dữ liệu thực'); 
        
        % Đánh dấu giá trị cần nội suy
        plot(app.UIAxes2, x_interp, y_interp, 'gx', 'MarkerSize', 10, 'LineWidth', 2, 'DisplayName', 'Giá trị nội suy');
        
        % Thiết lập tiêu đề và nhãn
        title(app.UIAxes2, ['Nội suy ' phuong_phap ' P(x)']);
        xlabel(app.UIAxes2, 'X');
        ylabel(app.UIAxes2, 'Y');
        legend(app.UIAxes2, 'show', 'Location', 'best');
        grid(app.UIAxes2, 'on');
        hold(app.UIAxes2, 'off');
        
    catch ME_plot
        uialert(app.UIFigure, ['Lỗi khi vẽ đồ thị: ' ME_plot.message], 'Lỗi Đồ thị');
    end
    
        end

        % Button pushed function: RunButton_3
        function RunButton_3Pushed(app, event)

    % 1. THU THẬP VÀ XỬ LÝ DỮ LIỆU ĐẦU VÀO
    
    try
        % Lấy dữ liệu X và Y 
        x_data = str2num(app.NhpdliuxEditField_2.Value); 
        y_data = str2num(app.NhpdliuyEditField_2.Value);
        
        % Lấy giá trị cần dự đoán
        x_predict = app.NhpgitrcndonEditField.Value;
        
        phuong_phap = app.ChnphngphpnisuyDropDown_2.Value; 
        
        if isempty(x_data) || isempty(y_data) || length(x_data) ~= length(y_data)
            uialert(app.UIFigure, 'Vui lòng nhập dữ liệu x và y hợp lệ (cùng kích thước).', 'Lỗi Dữ liệu');
            return;
        end
        n = length(x_data);
    catch ME
        uialert(app.UIFigure, ['Lỗi nhập liệu: ' ME.message], 'Lỗi Đầu vào');
        return;
    end
    
    % Khởi tạo các biến
    syms x;
    P_sym = 0; 
    reg_str = ''; % Chuỗi phương trình hồi quy
    y_predict = NaN;
    
    % Tính các tổng cần thiết cho Hồi quy Tuyến tính cơ bản: Y = a0 + a1*X
    sum_x = sum(x_data);
    sum_y = sum(y_data);
    sum_x2 = sum(x_data.^2);
    sum_xy = sum(x_data .* y_data);
    
    % Ma trận hệ số [n sum(x); sum(x) sum(x^2)]
    D = n * sum_x2 - sum_x^2;
    
    if D == 0
        uialert(app.UIFigure, 'Dữ liệu không đủ hoặc trùng lặp, không thể thực hiện hồi quy tuyến tính.', 'Lỗi Thuật toán');
        return;
    end


    % 2. THỰC HIỆN TÍNH TOÁN HỒI QUY
    
    if strcmp(phuong_phap, 'tuyến tính')
        % HỒI QUY TUYẾN TÍNH: Y = a0 + a1*X 
        
        a1 = (n * sum_xy - sum_x * sum_y) / D;
        a0 = (sum_y * sum_x2 - sum_x * sum_xy) / D;
        
        P_sym = a0 + a1 * x;
        reg_str = sprintf('Y = %.4f + %.4f * X', a0, a1);
        
    elseif strcmp(phuong_phap, 'hàm mũ') % HỒI QUY HÀM MŨ: Y = a*e^(b*X) (Tuyến tính hóa: ln(Y) = ln(a) + b*X)
        
        % Kiểm tra và xử lý logarit của Y
        if any(y_data <= 0)
            uialert(app.UIFigure, 'Giá trị Y phải > 0 để thực hiện Hồi quy Hàm Mũ.', 'Lỗi Dữ liệu');
            return;
        end
        Y_star = log(y_data); % Y* = ln(Y)
        
        % Tính các tổng mới cho mô hình Y* = A0 + A1*X
        sum_Y_star = sum(Y_star);
        sum_X_Y_star = sum(x_data .* Y_star);
        
        % Tính A1 (b) và A0 (ln(a))
        A1 = (n * sum_X_Y_star - sum_x * sum_Y_star) / D; % A1 = b
        A0 = (sum_Y_star * sum_x2 - sum_x * sum_X_Y_star) / D; % A0 = ln(a)
        
        b = A1;
        a = exp(A0);
        
        P_sym = a * exp(b * x);
        reg_str = sprintf('Y = %.4f * e^(%.4f * X)', a, b);
        
    elseif strcmp(phuong_phap, 'Logarit') % HỒI QUY LOGARIT: Y = a0 + a1*ln(X) (Tuyến tính hóa: Y = a0 + a1*X*)
        
        % Kiểm tra và xử lý logarit của X
        if any(x_data <= 0)
            uialert(app.UIFigure, 'Giá trị X phải > 0 để thực hiện Hồi quy Logarit.', 'Lỗi Dữ liệu');
            return;
        end
        X_star = log(x_data); % X* = ln(X)
        
        % Tính các tổng mới cho mô hình Y = a0 + a1*X*
        sum_X_star = sum(X_star);
        sum_X_star2 = sum(X_star.^2);
        sum_Y_X_star = sum(y_data .* X_star);
        
        % Ma trận hệ số mới [n sum(X*); sum(X*) sum(X*^2)]
        D_star = n * sum_X_star2 - sum_X_star^2;
        
        if D_star == 0
            uialert(app.UIFigure, 'Dữ liệu ln(X) không đủ hoặc trùng lặp.', 'Lỗi Thuật toán');
            return;
        end
        
        % Tính a1 và a0
        a1 = (n * sum_Y_X_star - sum_X_star * sum_y) / D_star;
        a0 = (sum_y * sum_X_star2 - sum_X_star * sum_Y_X_star) / D_star;
        
        P_sym = a0 + a1 * log(x);
        reg_str = sprintf('Y = %.4f + %.4f * ln(X)', a0, a1);
        
    end


    % 3. TÍNH KẾT QUẢ DỰ ĐOÁN
    
    if ~isnan(P_sym)
        % Chuyển đa thức về dạng hàm MATLAB để tính giá trị số
        P_func = matlabFunction(P_sym);
        
        % Kiểm tra điều kiện đầu vào cho hàm mũ/logarit khi dự đoán
        if strcmp(phuong_phap, 'Logarit') && x_predict <= 0
            uialert(app.UIFigure, 'Giá trị dự đoán X phải > 0 cho Hồi quy Logarit.', 'Lỗi Dự đoán');
            y_predict = NaN;
        else
            y_predict = P_func(x_predict);
        end
    end


    % 4. HIỂN THỊ KẾT QUẢ
    
    app.KtquphngtrnhhiquyTextArea.Value = reg_str;
    app.KtqudonEditField.Value = y_predict;


    % 5. VẼ ĐỒ THỊ     

    try
        % Tạo khoảng X để vẽ đồ thị 
        x_min = min(x_data);
        x_max = max(x_data);
        
        % Nếu hồi quy logarit và có giá trị dự đoán cần vẽ
        if strcmp(phuong_phap, 'Logarit') && x_predict > x_max
            x_max = x_predict;
        elseif strcmp(phuong_phap, 'Logarit') && x_predict < x_min
             x_min = x_predict;
        end
        
        % Điều chỉnh cho đồ thị đẹp hơn
        plot_min = x_min - 0.1 * (x_max - x_min);
        plot_max = x_max + 0.1 * (x_max - x_min);
        
        % Đảm bảo x > 0 cho logarit
        if strcmp(phuong_phap, 'Logarit') && plot_min <= 0
             plot_min = 0.001; % Đặt cận dưới > 0
        end

        X_plot = linspace(plot_min, plot_max, 500); 
        Y_plot = P_func(X_plot);
        
        % Xóa đồ thị cũ và thiết lập lại
        cla(app.UIAxes2_2);
        
        % Vẽ Hàm Hồi quy (Đường cong)
        plot(app.UIAxes2_2, X_plot, Y_plot, 'b-', 'LineWidth', 2, 'DisplayName', 'Hàm Hồi quy'); 
        hold(app.UIAxes2_2, 'on'); 
        
        % Vẽ Dữ liệu thực
        scatter(app.UIAxes2_2, x_data, y_data, 80, 'ro', 'filled', 'DisplayName', 'Dữ liệu thực'); 
        
        % Đánh dấu giá trị dự đoán
        if ~isnan(y_predict)
            scatter(app.UIAxes2_2, x_predict, y_predict, 100, 'gx', 'LineWidth', 2, 'DisplayName', 'Giá trị dự đoán');
        end
        
        % Thiết lập tiêu đề và nhãn
        title(app.UIAxes2_2, ['Hồi quy ' phuong_phap]);
        xlabel(app.UIAxes2_2, 'X');
        ylabel(app.UIAxes2_2, 'Y');
        legend(app.UIAxes2_2, 'show', 'Location', 'best');
        grid(app.UIAxes2_2, 'on');
        hold(app.UIAxes2_2, 'off');
        
    catch ME_plot
        uialert(app.UIFigure, ['Lỗi khi vẽ đồ thị: ' ME_plot.message], 'Lỗi Đồ thị');
    end
        end

        % Button pushed function: RunButton_4
        function RunButton_4Pushed(app, event)
    
    % 1. THU THẬP VÀ XÁC ĐỊNH PHƯƠNG THỨC NHẬP DỮ LIỆU
    
    phuong_phap = app.ChnphngphpohmDropDown.Value;
    sai_so = app.ChngitrsaisDropDown.Value;
    x0 = app.NhpgitrcntnhohmEditField.Value;
    derivative_result = NaN;
    
    % ĐỌC DỮ LIỆU ĐẦU VÀO
    
    % Bộ 1: Dữ liệu rời rạc
    x_data_discrete = str2num(app.NhpdliuxEditField_3.Value);
    y_data_discrete = str2num(app.NhpdliuyEditField_3.Value);
    
    % Bộ 2: Hàm số
    try
        f_str = app.Nhphm_sEditField.Value; 
    catch
        f_str = ''; % Xử lý nếu component không tồn tại hoặc không phải kiểu string
    end
    
    try
        h = app.Nhpbc_hEditField.Value;
    catch
        h = NaN; % Xử lý nếu component không tồn tại hoặc không phải kiểu numeric
    end
    
    % Xác định chế độ
    is_discrete_input = ~isempty(x_data_discrete) && ~isempty(y_data_discrete);
    is_function_input = ~isempty(f_str) && ~isnan(h) && (h > 0);
    
    if is_discrete_input && is_function_input
        uialert(app.UIFigure, 'Vui lòng chỉ nhập MỘT trong hai bộ dữ liệu.', 'Lỗi Đầu vào');
        return;
    elseif ~is_discrete_input && ~is_function_input
        uialert(app.UIFigure, 'Vui lòng nhập Dữ liệu rời rạc HOẶC Hàm số/Bước h (h phải > 0).', 'Lỗi Đầu vào');
        return;
    end


    % 2. XỬ LÝ THEO TRƯỜNG HỢP NHẬP LIỆU
    
    if is_discrete_input
        % TRƯỜNG HỢP 1: TÍNH TỪ DỮ LIỆU RỜI RẠC (X, Y)
        
        x_data = x_data_discrete;
        y_data = y_data_discrete;
        
        if length(x_data) ~= length(y_data)
             uialert(app.UIFigure, 'Dữ liệu x và y phải cùng kích thước.', 'Lỗi Dữ liệu');
             return;
        end
        
        % Tìm chỉ số i mà x_data(i) = x0
        idx = find(x_data == x0);
        if isempty(idx)
            uialert(app.UIFigure, 'Giá trị x cần tính đạo hàm không có trong dữ liệu rời rạc.', 'Lỗi Dữ liệu');
            return;
        end
        i = idx(1);
        n = length(x_data);
        
        % Giả định khoảng cách h đều
        h_discrete = x_data(2) - x_data(1);
        f_i = y_data(i);
        
        try
            if strcmp(phuong_phap, 'Xấp xỉ tiến')
                if strcmp(sai_so, 'O(h)')
                    % Yêu cầu: i+1
                    if i + 1 > n
                        error('Chỉ số vượt quá giới hạn.');
                    end
                    derivative_result = (y_data(i+1) - f_i) / h_discrete;
                else % O(h^2)
                    % Yêu cầu: i+2
                    if i + 2 > n
                        error('Chỉ số vượt quá giới hạn.');
                    end
                    derivative_result = (-y_data(i+2) + 4*y_data(i+1) - 3*f_i) / (2*h_discrete);
                end
                
            elseif strcmp(phuong_phap, 'Xấp xỉ lùi')
                if strcmp(sai_so, 'O(h)')
                    % Yêu cầu: i-1
                    if i - 1 < 1
                        error('Chỉ số vượt quá giới hạn.');
                    end
                    derivative_result = (f_i - y_data(i-1)) / h_discrete;
                else % O(h^2)
                    % Yêu cầu: i-2
                    if i - 2 < 1
                        error('Chỉ số vượt quá giới hạn.');
                    end
                    derivative_result = (3*f_i - 4*y_data(i-1) + y_data(i-2)) / (2*h_discrete);
                end
                
            elseif strcmp(phuong_phap, 'Xấp xỉ trung tâm')
                % Yêu cầu: i-1 và i+1
                if i-1 < 1 || i+1 > n
                    error('Chỉ số vượt quá giới hạn.');
                end
                derivative_result = (y_data(i+1) - y_data(i-1)) / (2*h_discrete);
            end
        catch % Xử lý lỗi chỉ số
            uialert(app.UIFigure, 'Không đủ điểm dữ liệu lân cận để áp dụng công thức đã chọn.', 'Lỗi Chỉ số');
            return;
        end
        
    elseif is_function_input
        % TRƯỜNG HỢP 2: TÍNH TỪ HÀM SỐ (f(x)) VÀ BƯỚC H 
        
        try
            syms x
            f_sym = str2sym(f_str);
            f_func = matlabFunction(f_sym);
        catch ME
            uialert(app.UIFigure, 'Hàm số nhập vào không hợp lệ.', 'Lỗi Hàm số');
            return;
        end
        
        % Tính f(x) tại các điểm lân cận
        f_x0 = f_func(x0);
        f_x0_plus_h = f_func(x0 + h);
        f_x0_minus_h = f_func(x0 - h);
        
        if strcmp(phuong_phap, 'Xấp xỉ tiến')
            if strcmp(sai_so, 'O(h)')
                derivative_result = (f_x0_plus_h - f_x0) / h;
            elseif strcmp(sai_so, 'O(h^2)')
                f_x0_plus_2h = f_func(x0 + 2*h);
                derivative_result = (-f_x0_plus_2h + 4*f_x0_plus_h - 3*f_x0) / (2*h);
            end
            
        elseif strcmp(phuong_phap, 'Xấp xỉ lùi')
            if strcmp(sai_so, 'O(h)')
                derivative_result = (f_x0 - f_x0_minus_h) / h;
            elseif strcmp(sai_so, 'O(h^2)')
                f_x0_minus_2h = f_func(x0 - 2*h);
                derivative_result = (3*f_x0 - 4*f_x0_minus_h + f_x0_minus_2h) / (2*h);
            end
            
        elseif strcmp(phuong_phap, 'Xấp xỉ trung tâm')
            % Luôn là O(h^2)
            derivative_result = (f_x0_plus_h - f_x0_minus_h) / (2*h);
        end
    end


    % 3. HIỂN THỊ KẾT QUẢ
    
    if ~isnan(derivative_result)
        app.KtquTextArea.Value = sprintf('%.8f', derivative_result);
    else
        app.KtquTextArea.Value = 'Lỗi tính toán/Dữ liệu không hợp lệ';
    end

        end

        % Button pushed function: RunButton_5
        function RunButton_5Pushed(app, event)
    
    % 1. THU THẬP VÀ XÁC ĐỊNH PHƯƠNG THỨC NHẬP DỮ LIỆU
    
    phuong_phap = app.ChnphngphptchphnDropDown.Value;
    I_result = NaN; % Tích phân kết quả
    
    % ĐỌC DỮ LIỆU ĐẦU VÀO
    
    % Bộ 1: Dữ liệu rời rạc (x, y)
    x_data = str2num(app.NhpdliuxEditField_4.Value); 
    y_data = str2num(app.NhpdliuyEditField_4.Value); 
    
    % Bộ 2: Hàm số, Cận, và N
    f_str = app.NhphmsEditField.Value;
    N = app.NhpNEditField.Value;
    
    % Xử lý chuỗi cận 'a, b'
    can_str = app.NhpcntnhtchphnEditField.Value;
    try
        can_parts = str2num(can_str);
        a = can_parts(1);
        b = can_parts(2);
    catch
        a = NaN; b = NaN;
    end
    
    % Xác định chế độ
    is_discrete_input = ~isempty(x_data) && ~isempty(y_data);
    is_function_input = ~isempty(f_str) && ~isnan(a) && ~isnan(b) && (a ~= b) && (N > 0);
    
    if is_discrete_input && is_function_input
        uialert(app.UIFigure, 'Vui lòng chỉ nhập MỘT trong hai bộ dữ liệu.', 'Lỗi Đầu vào');
        return;
    elseif ~is_discrete_input && ~is_function_input
        uialert(app.UIFigure, 'Vui lòng nhập Dữ liệu rời rạc HOẶC Hàm số/Cận/N hợp lệ.', 'Lỗi Đầu vào');
        return;
    end


    % 2. XỬ LÝ THEO TRƯỜNG HỢP NHẬP LIỆU
    
    if is_discrete_input
        % TRƯỜNG HỢP 1: TÍNH TỪ DỮ LIỆU RỜI RẠC (X, Y)
        
        if length(x_data) ~= length(y_data)
             uialert(app.UIFigure, 'Dữ liệu x và y phải cùng kích thước.', 'Lỗi Dữ liệu');
             return;
        end
        n_points = length(x_data);
        N_sections = n_points - 1; % Số đoạn
        
        % Giả định khoảng cách h đều
        h = x_data(2) - x_data(1);
        
        % Kiểm tra tính hợp lệ của N
        if any(diff(diff(x_data)) > 1e-9) % Kiểm tra h có đều không
             uialert(app.UIFigure, 'Phương pháp tích phân yêu cầu khoảng cách x (h) phải đều.', 'Lỗi Dữ liệu');
             return;
        end
        
        % Tính toán tích phân
        try
            if strcmp(phuong_phap, 'Hình thang') % Phương pháp Hình thang
                I_result = h/2 * (y_data(1) + 2*sum(y_data(2:N_sections)) + y_data(n_points));
                
            elseif strcmp(phuong_phap, 'Simpson 1/3') % Simpson 1/3
                if mod(N_sections, 2) ~= 0 
                    uialert(app.UIFigure, 'Simpson 1/3 yêu cầu số đoạn N phải chẵn (số điểm phải lẻ).', 'Lỗi Quy tắc');
                    return;
                end
                
                I_result = h/3 * (y_data(1) + y_data(n_points) + 4*sum(y_data(2:2:n_points-1)) + 2*sum(y_data(3:2:n_points-2)));
                
            elseif strcmp(phuong_phap, 'Simpson 3/8') % Simpson 3/8
                 if mod(N_sections, 3) ~= 0
                    uialert(app.UIFigure, 'Simpson 3/8 yêu cầu số đoạn N phải là bội số của 3.', 'Lỗi Quy tắc');
                    return;
                end
                
                % Công thức Hợp thành Simpson 3/8 (phức tạp hơn, đây là cách tổng quát)
                I_result = 0;
                for k = 1:3:N_sections
                    I_result = I_result + 3*h/8 * (y_data(k) + 3*y_data(k+1) + 3*y_data(k+2) + y_data(k+3));
                end
            end
        catch ME
             uialert(app.UIFigure, ['Lỗi tính toán tích phân rời rạc: ' ME.message], 'Lỗi Tính toán');
             return;
        end
        
    elseif is_function_input
        % TRƯỜNG HỢP 2: TÍNH TỪ HÀM SỐ (f(x))
        
        try
            syms x
            f_sym = str2sym(f_str);
            f_func = matlabFunction(f_sym);
        catch ME
            uialert(app.UIFigure, 'Hàm số nhập vào không hợp lệ.', 'Lỗi Hàm số');
            return;
        end
        
        h = (b - a) / N;
        
        % Tạo các điểm x
        x_points = linspace(a, b, N + 1);
        y_points = f_func(x_points);
        
        try
            if strcmp(phuong_phap, 'Hình thang') % Phương pháp Hình thang
                I_result = h/2 * (y_points(1) + 2*sum(y_points(2:N)) + y_points(N+1));
                
            elseif strcmp(phuong_phap, 'Simpson 1/3') % Simpson 1/3
                if mod(N, 2) ~= 0 
                    uialert(app.UIFigure, 'Simpson 1/3 yêu cầu số đoạn N phải chẵn.', 'Lỗi Quy tắc');
                    return;
                end
                
                I_result = h/3 * (y_points(1) + y_points(N+1) + 4*sum(y_points(2:2:N)) + 2*sum(y_points(3:2:N-1)));
                
            elseif strcmp(phuong_phap, 'Simpson 3/8') % Simpson 3/8
                 if mod(N, 3) ~= 0
                    uialert(app.UIFigure, 'Simpson 3/8 yêu cầu số đoạn N phải là bội số của 3.', 'Lỗi Quy tắc');
                    return;
                end
                
                I_result = 0;
                for k = 1:3:N
                    I_result = I_result + 3*h/8 * (y_points(k) + 3*y_points(k+1) + 3*y_points(k+2) + y_points(k+3));
                end
            end
        catch ME
             uialert(app.UIFigure, ['Lỗi tính toán tích phân hàm số: ' ME.message], 'Lỗi Tính toán');
             return;
        end
    end


    % 3. HIỂN THỊ KẾT QUẢ
    
    if ~isnan(I_result)
        app.KtquEditField.Value = I_result;
    else
        app.KtquEditField.Value = NaN;
        uialert(app.UIFigure, 'Không thể tính tích phân. Kiểm tra lại dữ liệu và quy tắc N.', 'Lỗi Kết quả');
    end

        end

        % Button pushed function: RunButton_6
        function RunButton_6Pushed(app, event)
           
            t = readtable("Book1.xlsx", "Sheet", 1)
            app.UITable.Data = t;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NgimTab = uitab(app.TabGroup);
            app.NgimTab.Title = 'Ngiệm';
            app.UIAxes = uiaxes(app.NgimTab);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpphngtrnhLabel = uilabel(app.NgimTab);
            app.NhpphngtrnhLabel.Text = 'Nhập phương trình:';
            app.NhpphngtrnhEditField = uieditfield(app.NgimTab, 'text');
            app.NhpphngtrnhEditField.Value = 'x^3 - 2*x - 5';
            app.ChnphngphptmLabel = uilabel(app.NgimTab);
            app.ChnphngphptmLabel.Text = 'Chọn phương pháp tìm:';
            app.ChnphngphptmDropDown = uidropdown(app.NgimTab);
            app.ChnphngphptmDropDown.Items = {'Chia đôi', 'Lặp', 'Newton (tiếp tuyến)'};
            app.ChnphngphptmDropDown.Value = 'Chia đôi';
            app.KtqunghimEditFieldLabel = uilabel(app.NgimTab);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm:';
            app.KtqunghimEditField = uieditfield(app.NgimTab, 'numeric');
            app.KtqunghimEditField.ValueDisplayFormat = '%11.8f';
            app.SlnlpLabel = uilabel(app.NgimTab);
            app.SlnlpLabel.Text = 'Số lần lặp:';
            app.SlnlpEditField = uieditfield(app.NgimTab, 'numeric');
            app.RunButton = uibutton(app.NgimTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NhpkhongphnlynghimEditFieldLabel = uilabel(app.NgimTab);
            app.NhpkhongphnlynghimEditFieldLabel.Text = 'Nhập khoảng phân ly nghiệm:';
            app.NhpkhongphnlynghimEditField = uieditfield(app.NgimTab, 'text');
            app.NhpkhongphnlynghimEditField.Value = '2, 3';
            app.NhpsaischophpEditFieldLabel = uilabel(app.NgimTab);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép:';
            app.NhpsaischophpEditField = uieditfield(app.NgimTab, 'numeric');
            app.NhpsaischophpEditField.Value = 0.0001;
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes2 = uiaxes(app.NisuyTab);
            title(app.UIAxes2, 'Đồ thị hàm số nội suy và dữ liệu thực')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.NhpgitrcnnisuyLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyLabel.Text = 'Nhập giá trị cần nội suy:';
            app.NhpgitrcnnisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.NhpgitrcnnisuyEditField.Value = 2.7;
            app.KtqunisuyLabel = uilabel(app.NisuyTab);
            app.KtqunisuyLabel.Text = 'Kết quả nội suy:';
            app.KtqunisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.KtqunisuyEditField.ValueDisplayFormat = '%11.8f';
            app.ChnphngphpnisuyLabel = uilabel(app.NisuyTab);
            app.ChnphngphpnisuyLabel.Text = 'Chọn phương pháp nội suy:';
            app.ChnphngphpnisuyDropDown = uidropdown(app.NisuyTab);
            app.ChnphngphpnisuyDropDown.Items = {'Lagrange', 'Newton'};
            app.ChnphngphpnisuyDropDown.Value = 'Lagrange';
            app.KtquathcnisuyLabel = uilabel(app.NisuyTab);
            app.KtquathcnisuyLabel.Text = 'Kết quả đa thức nội suy:';
            app.KtquathcnisuyTextArea = uitextarea(app.NisuyTab);
            app.RunButton_2 = uibutton(app.NisuyTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.NhpdliuxEditField_6Label = uilabel(app.NisuyTab);
            app.NhpdliuxEditField_6Label.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpdliuxEditField.Value = '2.0 2.5 3.0 3.5';
            app.NhpdliuyEditField_7Label = uilabel(app.NisuyTab);
            app.NhpdliuyEditField_7Label.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpdliuyEditField.Value = '0.6931 0.9163 1.0986 1.2528';
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.UIAxes2_2 = uiaxes(app.HiquyTab);
            title(app.UIAxes2_2, 'Đồ thị hàm số nội suy và dữ liệu thực')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.NhpgitrcndonLabel = uilabel(app.HiquyTab);
            app.NhpgitrcndonLabel.Text = 'Nhập giá trị cần dự đoán:';
            app.NhpgitrcndonEditField = uieditfield(app.HiquyTab, 'numeric');
            app.NhpgitrcndonEditField.Value = 3.4;
            app.KtqudonLabel = uilabel(app.HiquyTab);
            app.KtqudonLabel.Text = 'Kết quả dự đoán:';
            app.KtqudonEditField = uieditfield(app.HiquyTab, 'numeric');
            app.ChnphngphpnisuyDropDown_2Label = uilabel(app.HiquyTab);
            app.ChnphngphpnisuyDropDown_2Label.Text = 'Chọn phương pháp nội suy:';
            app.ChnphngphpnisuyDropDown_2 = uidropdown(app.HiquyTab);
            app.ChnphngphpnisuyDropDown_2.Items = {'tuyến tính', 'hàm mũ', 'Logarit'};
            app.ChnphngphpnisuyDropDown_2.Value = 'tuyến tính';
            app.KtquphngtrnhhiquyLabel = uilabel(app.HiquyTab);
            app.KtquphngtrnhhiquyLabel.Text = 'Kết quả phương trình hồi quy:';
            app.KtquphngtrnhhiquyTextArea = uitextarea(app.HiquyTab);
            app.RunButton_3 = uibutton(app.HiquyTab, 'push');
            app.RunButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunButton_3Pushed, true);
            app.RunButton_3.Text = 'Run';
            app.NhpdliuxEditField_6Label_2 = uilabel(app.HiquyTab);
            app.NhpdliuxEditField_6Label_2.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField_2 = uieditfield(app.HiquyTab, 'text');
            app.NhpdliuxEditField_2.Value = '1 2 3 4 5';
            app.NhpdliuyEditField_7Label_2 = uilabel(app.HiquyTab);
            app.NhpdliuyEditField_7Label_2.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField_2 = uieditfield(app.HiquyTab, 'text');
            app.NhpdliuyEditField_2.Value = '2 6 11 17 25';
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.HocLabel = uilabel(app.ohmTab);
            app.HocLabel.Text = 'Hoặc';
            app.ChngitrsaisDropDownLabel = uilabel(app.ohmTab);
            app.ChngitrsaisDropDownLabel.Text = 'Chọn giá trị sai số:';
            app.ChngitrsaisDropDown = uidropdown(app.ohmTab);
            app.ChngitrsaisDropDown.Items = {'O(h)', 'O(h^2)'};
            app.ChngitrsaisDropDown.Value = 'O(h)';
            app.ChnphngphpohmLabel = uilabel(app.ohmTab);
            app.ChnphngphpohmLabel.Text = 'Chọn phương pháp đạo hàm:';
            app.ChnphngphpohmDropDown = uidropdown(app.ohmTab);
            app.ChnphngphpohmDropDown.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.ChnphngphpohmDropDown.Value = 'Xấp xỉ tiến';
            app.NhpgitrcntnhohmLabel = uilabel(app.ohmTab);
            app.NhpgitrcntnhohmLabel.Text = 'Nhập giá trị cần tính đạo hàm:';
            app.NhpgitrcntnhohmEditField = uieditfield(app.ohmTab, 'numeric');
            app.NhpgitrcntnhohmEditField.Value = 1;
            app.KtquLabel_2 = uilabel(app.ohmTab);
            app.KtquLabel_2.Text = 'Kết quả:';
            app.KtquTextArea = uitextarea(app.ohmTab);
            app.RunButton_4 = uibutton(app.ohmTab, 'push');
            app.RunButton_4.ButtonPushedFcn = createCallbackFcn(app, @RunButton_4Pushed, true);
            app.RunButton_4.Text = 'Run';
            app.NhpdliuxEditField_6Label_3 = uilabel(app.ohmTab);
            app.NhpdliuxEditField_6Label_3.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField_3 = uieditfield(app.ohmTab, 'text');
            app.NhpdliuyEditField_7Label_3 = uilabel(app.ohmTab);
            app.NhpdliuyEditField_7Label_3.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField_3 = uieditfield(app.ohmTab, 'text');
            app.NhpdliuyEditField_7Label_4 = uilabel(app.ohmTab);
            app.NhpdliuyEditField_7Label_4.Text = 'Nhập hàm số:';
            app.Nhphm_sEditField = uieditfield(app.ohmTab, 'text');
            app.Nhphm_sEditField.Value = 'x^3 - 2*x + 1';
            app.NhpbchEditFieldLabel = uilabel(app.ohmTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h:';
            app.Nhpbc_hEditField = uieditfield(app.ohmTab, 'numeric');
            app.Nhpbc_hEditField.Value = 0.1;
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.HocLabel_2 = uilabel(app.TchphnTab);
            app.HocLabel_2.Text = 'Hoặc ';
            app.NhphmsEditFieldLabel = uilabel(app.TchphnTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số:';
            app.NhphmsEditField = uieditfield(app.TchphnTab, 'text');
            app.NhphmsEditField.Value = 'x^2';
            app.ChnphngphptchphnDropDownLabel = uilabel(app.TchphnTab);
            app.ChnphngphptchphnDropDownLabel.Text = 'Chọn phương pháp tích phân:';
            app.ChnphngphptchphnDropDown = uidropdown(app.TchphnTab);
            app.ChnphngphptchphnDropDown.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.ChnphngphptchphnDropDown.Value = 'Hình thang';
            app.NhpcntnhtchphnEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpcntnhtchphnEditFieldLabel.Text = 'Nhập cận để tính tích phân:';
            app.NhpcntnhtchphnEditField = uieditfield(app.TchphnTab, 'text');
            app.NhpcntnhtchphnEditField.Value = '0, 2';
            app.NhpNLabel = uilabel(app.TchphnTab);
            app.NhpNLabel.Text = 'Nhập N:';
            app.NhpNEditField = uieditfield(app.TchphnTab, 'numeric');
            app.NhpNEditField.Value = 4;
            app.KtquLabel = uilabel(app.TchphnTab);
            app.KtquLabel.Text = 'Kết quả:';
            app.KtquEditField = uieditfield(app.TchphnTab, 'numeric');
            app.RunButton_5 = uibutton(app.TchphnTab, 'push');
            app.RunButton_5.ButtonPushedFcn = createCallbackFcn(app, @RunButton_5Pushed, true);
            app.RunButton_5.Text = 'Run';
            app.NhpdliuxEditField_4Label = uilabel(app.TchphnTab);
            app.NhpdliuxEditField_4Label.Text = 'Nhập dữ liệu x:';
            app.NhpdliuxEditField_4 = uieditfield(app.TchphnTab, 'text');
            app.NhpdliuyEditField_5Label = uilabel(app.TchphnTab);
            app.NhpdliuyEditField_5Label.Text = 'Nhập dữ liệu y:';
            app.NhpdliuyEditField_4 = uieditfield(app.TchphnTab, 'text');
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.UITable = uitable(app.GiithiunhmTab);
            app.UITable.ColumnName = {'Tên '; 'MSSV'; 'Nhiệm vụ'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.RunButton_6 = uibutton(app.GiithiunhmTab, 'push');
            app.RunButton_6.ButtonPushedFcn = createCallbackFcn(app, @RunButton_6Pushed, true);
            app.RunButton_6.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DOAN_THPPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end